classdef POSTPROCESS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        DataImportTab                matlab.ui.container.Tab
        UploadFileButton             matlab.ui.control.Button
        PerformancePlotsTab          matlab.ui.container.Tab
        PlotConfigLabel              matlab.ui.control.Label
        YUpperBoundEditField         matlab.ui.control.NumericEditField
        YUpperBoundEditFieldLabel    matlab.ui.control.Label
        YLowerBoundEditField_2       matlab.ui.control.NumericEditField
        YLowerBoundEditField_2Label  matlab.ui.control.Label
        XUpperBoundEditField         matlab.ui.control.NumericEditField
        XUpperBoundEditFieldLabel    matlab.ui.control.Label
        XLowerBoundEditField         matlab.ui.control.NumericEditField
        XLowerBoundEditFieldLabel    matlab.ui.control.Label
        YAxisDropDown                matlab.ui.control.DropDown
        YAxisDropDownLabel           matlab.ui.control.Label
        XAxisDropDown                matlab.ui.control.DropDown
        XAxisDropDownLabel           matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        StructuralPlotsTab           matlab.ui.container.Tab
        YAxisDropDown_2              matlab.ui.control.DropDown
        YAxisDropDown_2Label         matlab.ui.control.Label
        AngleofAttackdegLabel        matlab.ui.control.Label
        Slider                       matlab.ui.control.Slider
        UIAxes5                      matlab.ui.control.UIAxes
        DynamicPlotsTab              matlab.ui.container.Tab
        Plot1DropDown                matlab.ui.control.DropDown
        Plot1DropDownLabel           matlab.ui.control.Label
        UIAxes6                      matlab.ui.control.UIAxes
        TrajectoryPlotTab            matlab.ui.container.Tab
        UIAxes2                      matlab.ui.control.UIAxes
        FlightVisualizationTab       matlab.ui.container.Tab
        RollRateLabel                matlab.ui.control.Label
        AltitudekmGauge              matlab.ui.control.LinearGauge
        AltitudekmGaugeLabel         matlab.ui.control.Label
        StopButton                   matlab.ui.control.Button
        TimeLabel                    matlab.ui.control.Label
        PlayButton                   matlab.ui.control.Button
        MachGauge                    matlab.ui.control.Gauge
        MachGaugeLabel               matlab.ui.control.Label
        UIAxes3                      matlab.ui.control.UIAxes
        DispersionSummaryTab         matlab.ui.container.Tab
        ApogeeLabel                  matlab.ui.control.Label
        UIAxes4                      matlab.ui.control.UIAxes
        RecoveryCalculationsTab      matlab.ui.container.Tab
        DeploymentAnalysisLabel      matlab.ui.control.Label
        DescentRatesLabel            matlab.ui.control.Label
        DragSeparationForceLabel     matlab.ui.control.Label
        WarningUses4xcorrectionfactorfromempericalBPtestsforMambaLabel  matlab.ui.control.Label
        Qtyof440NylonShearPinsDragSepLabel  matlab.ui.control.Label
        BlackPowerQtygLabel          matlab.ui.control.Label
        MainDescentRateftsAtImpactLabel  matlab.ui.control.Label
        DrogueDescentRateftsAtMainDeployLabel  matlab.ui.control.Label
        RecoveryOutputsLabel         matlab.ui.control.Label
        IMPORTANTTab                 matlab.ui.container.Tab
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        X_N
        Y_N
        X
        Y
        X2
        Y2
        Z2
        Data
        Filename
        XLL
        XLU
        YLL
        YLU
        Stop
        disp
        dsep
        FX
        FY
        FAOA
        struct
        dynamics
        DX
        DY
        DY2
    end
    
    methods (Access = private)
        
        function results = BtoI(app, in, q0, q1, q2, q3)
            %Transformation matrix from quaternions 
            T = [2*(q0^2+q1^2)-1, 2*(q1*q2-q0*q3), 2*(q1*q3+q0*q2); ...
                2*(q1*q2+q0*q3), 2*(q0^2+q2^2)-1, 2*(q2*q3-q0*q1); ...
                2*(q1*q3-q0*q2), 2*(q2*q3+q0*q1), 2*(q0^2+q3^2)-1];

            %Output vector
            results = T*in;
        end
        
        
        function [qtyPins, F_sep] = DragSeparation(app, Mach, L_N, L_F, L_B, A_ref, q, dx, x, x_c, dmdx)

            data = readmatrix("CDDataFile0.txt", "Delimiter", ["   ", "  "]);
            data(isnan(data(:, 1)), :) = [];
            data_A = data(1:6:end, :);
            x_c = x_c;
            index = round(Mach*100);
            data_A = data_A(index, :);
            c_index = round(x_c/dx);

            i_N = round(L_N/dx);
            i_B = round((L_B+L_N)/dx);
            i_F = round((L_N+L_B+L_F)/dx);


            %% Axial Distribution
            C_A_Tot = data_A(3);
            F_A_Tot = C_A_Tot*A_ref*q;
            F_A_Tot = -F_A_Tot;

            W_A = zeros(size(x));

            %Nosecone
            C_A_N = L_N/(L_N+L_B)*data_A(5)+data_A(6);
            const = C_A_N*2/L_N;
            W_A(1:i_N) = W_A(1:i_N)+const*x(1:i_N);

            %Body
            C_A_B = data_A(5)*(L_B/(L_N+L_B))+data_A(12);
            W_A(i_N+1:i_B) = W_A(i_N+1:i_B)+C_A_B/L_B;

            %Fins
            C_A_F = data_A(8)+data_A(9)+data_A(10)+data_A(11);
            W_A(i_B+1:i_F) = W_A(i_B+1:i_F)+C_A_F/L_F;

            %Base
            C_Base = data_A(7);
            
            m1 = trapz(x(1:c_index), dmdx(1:c_index));
            m2 = trapz(x(1+c_index:end), dmdx(1+c_index:end));
            F1 = trapz(x(1:c_index), W_A(1:c_index))*A_ref*q;
            F2 = (trapz(x(1+c_index:end), W_A(1+c_index:end))+C_Base)*A_ref*q;

            a_cg = abs(F_A_Tot/(m1+m2));
            F_sep = abs(m1*a_cg-F1);
            F_sep_2 = abs(m2*a_cg-F2);
            
            qtyPins = abs(max([F_sep, F_sep_2])/4.448/40)*1.5;

            qtyPins = 2*ceil(qtyPins/2);

        end


        function weight = Blackpowder(app, r_v, l_c, qty)

            F = qty * 40;
            A = r_v^2*pi;
            V = A*l_c;

            P = F/A;

            weight = 3*454*P*V/266/3307;
            
        end
        
        function [DRM, DRD] = DescentRate(app, m, h0, hd, am, cdm, ad, cdd)
            [~, ~, ~, rhod] = atmosisa(hd);
            [~, ~, ~, rhom] = atmosisa(h0);

            DRM = sqrt(2*m*9.81/rhom/am/cdm);
            DRD = sqrt(2*m*9.81/rhod/ad/cdd);
        end
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Stop = false;
            app.MachGauge.Limits = [0 10];
            app.AltitudekmGauge.Limits = [0 100];
            set(app.UIAxes,'color','black')
            set(app.UIAxes2,'color','black')
            set(app.UIAxes3,'color','black')
            set(app.UIAxes4,'color','black')
            xlabel(app.UIAxes, "Time");
            ylabel(app.UIAxes, "Time");

            
        end

        % Value changed function: XAxisDropDown
        function XAxisDropDownValueChanged(app, event)
            app.X_N = app.XAxisDropDown.Value;
            app.X = app.Data{:, app.X_N};
            plot(app.UIAxes, app.X, app.Y, 'w');
            xlabel(app.UIAxes, app.X_N);
            title(app.UIAxes, strcat('\color{white}{', app.Y_N, " vs. ", app.X_N, '}'))
        end

        % Value changed function: YAxisDropDown
        function YAxisDropDownValueChanged(app, event)
            app.Y_N = app.YAxisDropDown.Value;
            app.Y = app.Data.(app.Y_N);
            plot(app.UIAxes, app.X, app.Y, 'w');
            ylabel(app.UIAxes, app.Y_N);
            title(app.UIAxes, strcat('\color{white}{', app.Y_N, " vs. ", app.X_N, '}'))
        end

        % Callback function
        function FileNameEditFieldValueChanged(app, event)
            app.Filename = app.FileNameEditField.Value;
            
        end

        % Value changed function: XLowerBoundEditField, 
        % ...and 1 other component
        function XLowerBoundEditFieldValueChanged(app, event)
            app.XLL = app.XLowerBoundEditField.Value;
            app.XLU = app.XUpperBoundEditField.Value;
            xlim(app.UIAxes, [app.XLL, app.XLU]);
            
        end

        % Value changed function: YLowerBoundEditField_2, 
        % ...and 1 other component
        function YLowerBoundEditField_2ValueChanged(app, event)
            app.YLL = app.YLowerBoundEditField_2.Value;
            app.YLU = app.YUpperBoundEditField.Value;
            ylim(app.UIAxes, [app.YLL, app.YLU]);
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            q0 = app.Data.("q0");
            q1 = app.Data.("q1");
            q2 = app.Data.("q2");
            q3 = app.Data.("q3");
            time = app.Data.("Time");
            q0 = q0(1:4:end);
            q1 = q1(1:4:end);
            q2 = q2(1:4:end);
            q3 = q3(1:4:end);
            time = time(1:4:end);
            Mach = app.Data.("Mach");
            Mach = Mach(1:4:end);
            Altitude = app.Data.("Altitude");
            Altitude = Altitude(1:4:end);
            P = app.Data.("Roll Rate")*60/2/pi;
            P = P(1:4:end);
            for i = 1:length(q0)
                orientationx(:, i) = BtoI(app, [1; 0; 0], q0(i), q1(i), q2(i), q3(i));
                orientationy(:, i) = BtoI(app, [0; 1; 0], q0(i), q1(i), q2(i), q3(i));
                orientationz(:, i) = BtoI(app, [0; 0; 1], q0(i), q1(i), q2(i), q3(i));
            end

            for i = 1:length(q0)

                hold(app.UIAxes3, 'off');
                plot3(app.UIAxes3, [0, orientationx(3, i)], [0, orientationx(2, i)], [0, orientationx(1, i)], 'r');
                hold(app.UIAxes3, 'on');
                plot3(app.UIAxes3, [0, orientationy(3, i)], [0, orientationy(2, i)], [0, orientationy(1, i)], 'g');
                plot3(app.UIAxes3, [0, orientationz(3, i)], [0, orientationz(2, i)], [0, orientationz(1, i)], 'b');    
                xlim(app.UIAxes3, [-1 1]);
                ylim(app.UIAxes3, [-1 1]);
                zlim(app.UIAxes3, [-1 1]);
                drawnow

                app.TimeLabel.Text = strcat("Time: ", string(time(i)), " s");
                if(app.Stop)
                    app.Stop = false;
                    break
                end

                app.MachGauge.Value = Mach(i);
                app.AltitudekmGauge.Value = Altitude(i)/1000;
                app.RollRateLabel.Text = strcat("Roll Rate: ", string(P(i)), " RPM");
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Stop = true;
        end

        % Button pushed function: UploadFileButton
        function UploadFileButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');
            load(file, 'output', 'dispersion', 'Dsep','Struct', 'Dyn');
            app.Data = output;
            app.Y = app.Data{:, 1};
            app.X = app.Data{:, 1};
            app.X2 = app.Data.("Longitude");
            app.Y2 = app.Data.("Latitude");
            app.Z2 = app.Data.("Altitude");
            plot3(app.UIAxes2, app.X2, app.Y2, app.Z2, 'w')
            xlabel(app.UIAxes2, "Longitude");
            ylabel(app.UIAxes2, "Latitude");
            zlabel(app.UIAxes2, "Altitude");
            app.XLL = inf;
            app.XLU = inf;
            app.YLL = inf;
            app.YLU = inf;
            app.disp = dispersion;
            theta = 0:0.01:2*pi;
            rad = app.disp.rad;
            ME = app.disp.ME;
            MN = app.disp.MN;
            dE = app.disp.dE;
            dN = app.disp.dN;
            x1 = rad*cos(theta)+ME;
            y1 = rad*sin(theta)+MN;
            x2 = 2*rad*cos(theta)+ME;
            y2 = 2*rad*sin(theta)+MN;
            x3 = 3*rad*cos(theta)+ME;
            y3 = 3*rad*sin(theta)+MN;
            hold(app.UIAxes4, 'on')
            plot(app.UIAxes4, dE, dN, 'ro', "MarkerSize", 2);
            plot(app.UIAxes4, x1, y1, 'r-', x2, y2, 'r-', x3, y3, 'r-');
            plot(app.UIAxes4, 0, 0, 'b.', "MarkerSize", 30);
            xlim(app.UIAxes4, [-500, inf]);
            hold(app.UIAxes4, 'off')
            app.ApogeeLabel.Text = strcat("Apogee: ", string(round(app.disp.meanA*3.281)), " +/- ", string(round(app.disp.stdA*3.281*2)), " m");

            Qty = Dsep.QTYPins;
            BP = Dsep.W_BP;
            F_sep = Dsep.F_sep;
            DRM = Dsep.V_M;
            DRD = Dsep.V_D;

            app.Qtyof440NylonShearPinsDragSepLabel.Text = strcat("QTY of 4-40 Nylon Shear Pins: ", string(Qty));
            app.BlackPowerQtygLabel.Text = strcat("Black Power Quantity [g]: ", string(BP));
            app.MainDescentRateftsAtImpactLabel.Text = strcat("Main Descent Rate [ft/s] (at impact): ", string(DRM*3.281));
            app.DrogueDescentRateftsAtMainDeployLabel.Text = strcat("Drogue Descent Rate [ft/s] (at main deploy): ", string(DRD*3.281));
            app.DragSeparationForceLabel.Text = strcat("Drag Separation Force: ", string(F_sep/4.448));
            
            app.struct = Struct;
            app.FAOA = 0;
            app.FX = Struct.x;
            app.FY = Struct.N(:, app.FAOA+1);
            plot(app.UIAxes5, app.FX, app.FY, 'w');

            app.dynamics = Dyn;
            app.DX = Dyn.t;
            app.DY = Dyn.C1;
            plot(app.UIAxes6, app.DX, app.DY, 'w');
            
            
        end

        % Value changed function: YAxisDropDown_2
        function YAxisDropDown_2ValueChanged(app, event)
            value = app.YAxisDropDown_2.Value;
            app.FAOA = app.Slider.Value;
            switch value
                case "Normal Force"
                    app.FY = app.struct.N(:, app.FAOA+1);
                case "Shear Force"
                    app.FY = app.struct.V(:, app.FAOA+1);
                case "Bending Moment"
                    app.FY = app.struct.M(:, app.FAOA+1);
            end

            plot(app.UIAxes5, app.FX, app.FY, 'w');
            title(app.UIAxes5, strcat("Vehicle ", value, " @ AoA = ", string(app.FAOA)), 'Color', 'w')
            
            if(value == "Normal Force" || value == "Shear Force")
                unit = "N";
            else
                unit = "N-m";
            end
            ylabel(app.UIAxes5, strcat(value, " [", unit, "]"))
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.YAxisDropDown_2.Value;
            app.FAOA = app.Slider.Value;
            event.Source.Value = round(app.FAOA);
            app.FAOA = round(app.FAOA);
            switch value
                case "Normal Force"
                    app.FY = app.struct.N(:, app.FAOA+1);
                case "Shear Force"
                    app.FY = app.struct.V(:, app.FAOA+1);
                case "Bending Moment"
                    app.FY = app.struct.M(:, app.FAOA+1);
            end
            plot(app.UIAxes5, app.FX, app.FY, 'w');
            title(app.UIAxes5, strcat("Vehicle ", value, " @ AoA = ", string(app.FAOA)), 'Color', 'w');

        end

        % Value changed function: Plot1DropDown
        function Plot1DropDownValueChanged(app, event)
            SDY = app.Plot1DropDown.Value;
            switch SDY
                case "Corrective Coefficient"
                    app.DY = app.dynamics.C1;
                case "Damping Coefficient"
                    app.DY = app.dynamics.C2;
                case "Natural Frequency"
                    app.DY = app.dynamics.omega_n;
                case "Damping Ratio"
                    app.DY = app.dynamics.zeta;
                case "Coupled Natural Frequency"
                    app.DY = app.dynamics.omega_n_c;
                case "Coupled Damping Ratio"
                    app.DY = app.dynamics.zeta_c;
                case "Resonant Amplification Ratio"
                    app.DY = app.dynamics.AR_res;
                case "Roll Rate"
                    app.DY = app.dynamics.P;
                case "None"
                    app.DY = zeros(length(app.DX));
            end
            plot(app.UIAxes6, app.DX, app.DY, 'w');
            ylabel(app.UIAxes6, SDY);

        end

        % Callback function
        function Plot2DropDownValueChanged(app, event)
            SDY = app.Plot2DropDown.Value;
            switch SDY
                case "Corrective Coefficient"
                    app.DY2 = app.dynamics.C1;
                case "Damping Coefficient"
                    app.DY2 = app.dynamics.C2;
                case "Natural Frequency"
                    app.DY2 = app.dynamics.omega_n;
                case "Damping Ratio"
                    app.DY2 = app.dynamics.zeta;
                case "Coupled Natural Frequency"
                    app.DY2 = app.dynamics.omega_n_c;
                case "Coupled Damping Ratio"
                    app.DY2 = app.dynamics.zeta_c;
                case "Resonant Amplification Ratio"
                    app.DY2 = app.dynamics.AR_res;
                case "Roll Rate"
                    app.DY2 = app.dynamics.P;
                case "None"
                    app.DY2 = zeros(length(app.DX));
            end
            plot(app.UIAxes6, app.DX, app.DY, 'w', app.DX, app.DY2, 'r');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataImportTab = uitab(app.TabGroup);
            app.DataImportTab.Title = 'Data Import';
            app.UploadFileButton = uibutton(app.DataImportTab, 'push');
            app.UploadFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFileButtonPushed, true);
            app.UploadFileButton.Text = 'Upload File';
            app.PerformancePlotsTab = uitab(app.TabGroup);
            app.PerformancePlotsTab.Title = 'Performance Plots';
            app.UIAxes = uiaxes(app.PerformancePlotsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.XAxisDropDownLabel = uilabel(app.PerformancePlotsTab);
            app.XAxisDropDownLabel.Text = 'X Axis';
            app.XAxisDropDown = uidropdown(app.PerformancePlotsTab);
            app.XAxisDropDown.Items = {'Time', 'Altitude', 'Speed', 'Acceleration', 'Mach', 'Stability Margin', 'AoA', 'CG', 'CP', 'Mass', 'Dynamic Pressure', 'Normal Force', 'Drag Force', 'Thrust Force', 'Latitude', 'Longitude', 'Roll Rate', 'Pitch Rate', 'Yaw Rate'};
            app.XAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisDropDownValueChanged, true);
            app.XAxisDropDown.Value = 'Time';
            app.YAxisDropDownLabel = uilabel(app.PerformancePlotsTab);
            app.YAxisDropDownLabel.Text = 'Y Axis';
            app.YAxisDropDown = uidropdown(app.PerformancePlotsTab);
            app.YAxisDropDown.Items = {'Time', 'Altitude', 'Speed', 'Acceleration', 'Mach', 'Stability Margin', 'AoA', 'CG', 'CP', 'Mass', 'Dynamic Pressure', 'Normal Force', 'Drag Force', 'Thrust Force', 'Latitude', 'Longitude', 'Roll Rate', 'Pitch Rate', 'Yaw Rate'};
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDownValueChanged, true);
            app.YAxisDropDown.Value = 'Time';
            app.XLowerBoundEditFieldLabel = uilabel(app.PerformancePlotsTab);
            app.XLowerBoundEditFieldLabel.Text = 'X Lower Bound';
            app.XLowerBoundEditField = uieditfield(app.PerformancePlotsTab, 'numeric');
            app.XLowerBoundEditField.ValueChangedFcn = createCallbackFcn(app, @XLowerBoundEditFieldValueChanged, true);
            app.XUpperBoundEditFieldLabel = uilabel(app.PerformancePlotsTab);
            app.XUpperBoundEditFieldLabel.Text = 'X Upper Bound';
            app.XUpperBoundEditField = uieditfield(app.PerformancePlotsTab, 'numeric');
            app.XUpperBoundEditField.ValueChangedFcn = createCallbackFcn(app, @XLowerBoundEditFieldValueChanged, true);
            app.YLowerBoundEditField_2Label = uilabel(app.PerformancePlotsTab);
            app.YLowerBoundEditField_2Label.Text = 'Y Lower Bound';
            app.YLowerBoundEditField_2 = uieditfield(app.PerformancePlotsTab, 'numeric');
            app.YLowerBoundEditField_2.ValueChangedFcn = createCallbackFcn(app, @YLowerBoundEditField_2ValueChanged, true);
            app.YUpperBoundEditFieldLabel = uilabel(app.PerformancePlotsTab);
            app.YUpperBoundEditFieldLabel.Text = 'Y Upper Bound';
            app.YUpperBoundEditField = uieditfield(app.PerformancePlotsTab, 'numeric');
            app.YUpperBoundEditField.ValueChangedFcn = createCallbackFcn(app, @YLowerBoundEditField_2ValueChanged, true);
            app.PlotConfigLabel = uilabel(app.PerformancePlotsTab);
            app.PlotConfigLabel.Text = 'Plot Config';
            app.StructuralPlotsTab = uitab(app.TabGroup);
            app.StructuralPlotsTab.Title = 'Structural Plots';
            app.UIAxes5 = uiaxes(app.StructuralPlotsTab);
            title(app.UIAxes5, '\color{white}Vehicle Normal Force')
            xlabel(app.UIAxes5, 'Distance from Tip [m]')
            ylabel(app.UIAxes5, 'Normal Force [N]')
            zlabel(app.UIAxes5, 'Z')
            app.Slider = uislider(app.StructuralPlotsTab);
            app.Slider.Limits = [0 10];
            app.Slider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Slider.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.AngleofAttackdegLabel = uilabel(app.StructuralPlotsTab);
            app.AngleofAttackdegLabel.Text = 'Angle of Attack [deg]';
            app.YAxisDropDown_2Label = uilabel(app.StructuralPlotsTab);
            app.YAxisDropDown_2Label.Text = 'Y Axis';
            app.YAxisDropDown_2 = uidropdown(app.StructuralPlotsTab);
            app.YAxisDropDown_2.Items = {'Normal Force', 'Shear Force', 'Bending Moment'};
            app.YAxisDropDown_2.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDown_2ValueChanged, true);
            app.YAxisDropDown_2.Value = 'Normal Force';
            app.DynamicPlotsTab = uitab(app.TabGroup);
            app.DynamicPlotsTab.Title = 'Dynamic Plots';
            app.UIAxes6 = uiaxes(app.DynamicPlotsTab);
            title(app.UIAxes6, '\color{white}Dynamic Plots')
            xlabel(app.UIAxes6, 'Time [s]')
            ylabel(app.UIAxes6, 'Corrective Coefficient [N-m/rad]')
            zlabel(app.UIAxes6, 'Z')
            app.Plot1DropDownLabel = uilabel(app.DynamicPlotsTab);
            app.Plot1DropDownLabel.Text = 'Plot 1';
            app.Plot1DropDown = uidropdown(app.DynamicPlotsTab);
            app.Plot1DropDown.Items = {'Corrective Coefficient', 'Damping Coefficient', 'Natural Frequency', 'Damping Ratio', 'Coupled Natural Frequency', 'Coupled Damping Ratio', 'Resonant Amplification Ratio', 'Roll Rate'};
            app.Plot1DropDown.ValueChangedFcn = createCallbackFcn(app, @Plot1DropDownValueChanged, true);
            app.Plot1DropDown.Value = 'Corrective Coefficient';
            app.TrajectoryPlotTab = uitab(app.TabGroup);
            app.TrajectoryPlotTab.Title = 'Trajectory Plot';
            app.UIAxes2 = uiaxes(app.TrajectoryPlotTab);
            title(app.UIAxes2, '\color{white}Trajectory')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.FlightVisualizationTab = uitab(app.TabGroup);
            app.FlightVisualizationTab.Title = 'Flight Visualization';
            app.UIAxes3 = uiaxes(app.FlightVisualizationTab);
            title(app.UIAxes3, '\color{white}Orientation')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.MachGaugeLabel = uilabel(app.FlightVisualizationTab);
            app.MachGaugeLabel.Text = 'Mach';
            app.MachGauge = uigauge(app.FlightVisualizationTab, 'circular');
            app.PlayButton = uibutton(app.FlightVisualizationTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.TimeLabel = uilabel(app.FlightVisualizationTab);
            app.TimeLabel.Text = 'Time: ';
            app.StopButton = uibutton(app.FlightVisualizationTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.AltitudekmGaugeLabel = uilabel(app.FlightVisualizationTab);
            app.AltitudekmGaugeLabel.Text = 'Altitude [km]';
            app.AltitudekmGauge = uigauge(app.FlightVisualizationTab, 'linear');
            app.RollRateLabel = uilabel(app.FlightVisualizationTab);
            app.RollRateLabel.Text = 'Roll Rate:';
            app.DispersionSummaryTab = uitab(app.TabGroup);
            app.DispersionSummaryTab.Title = 'Dispersion Summary';
            app.UIAxes4 = uiaxes(app.DispersionSummaryTab);
            title(app.UIAxes4, '\color{white}{Dispersion Summary}')
            xlabel(app.UIAxes4, 'East/West [m]')
            ylabel(app.UIAxes4, 'North/South [m]')
            zlabel(app.UIAxes4, 'Z')
            app.ApogeeLabel = uilabel(app.DispersionSummaryTab);
            app.ApogeeLabel.Text = 'Apogee:';
            app.RecoveryCalculationsTab = uitab(app.TabGroup);
            app.RecoveryCalculationsTab.Title = 'Recovery Calculations';
            app.RecoveryOutputsLabel = uilabel(app.RecoveryCalculationsTab);
            app.RecoveryOutputsLabel.Text = 'Recovery Outputs';
            app.DrogueDescentRateftsAtMainDeployLabel = uilabel(app.RecoveryCalculationsTab);
            app.DrogueDescentRateftsAtMainDeployLabel.Text = 'Drogue Descent Rate [ft/s] (At Main Deploy)';
            app.MainDescentRateftsAtImpactLabel = uilabel(app.RecoveryCalculationsTab);
            app.MainDescentRateftsAtImpactLabel.Text = 'Main Descent Rate [ft/s] (At Impact)';
            app.BlackPowerQtygLabel = uilabel(app.RecoveryCalculationsTab);
            app.BlackPowerQtygLabel.Text = 'Black Power Qty [g]';
            app.Qtyof440NylonShearPinsDragSepLabel = uilabel(app.RecoveryCalculationsTab);
            app.Qtyof440NylonShearPinsDragSepLabel.Text = 'Qty of 4-40 Nylon Shear Pins (Drag Sep):';
            app.WarningUses4xcorrectionfactorfromempericalBPtestsforMambaLabel = uilabel(app.RecoveryCalculationsTab);
            app.WarningUses4xcorrectionfactorfromempericalBPtestsforMambaLabel.Text = 'Warning: Uses 4x correction factor from emperical BP tests for Mamba, start lower';
            app.DragSeparationForceLabel = uilabel(app.RecoveryCalculationsTab);
            app.DragSeparationForceLabel.Text = 'Drag Separation Force: ';
            app.DescentRatesLabel = uilabel(app.RecoveryCalculationsTab);
            app.DescentRatesLabel.Text = 'Descent Rates';
            app.DeploymentAnalysisLabel = uilabel(app.RecoveryCalculationsTab);
            app.DeploymentAnalysisLabel.Text = 'Deployment Analysis';
            app.IMPORTANTTab = uitab(app.TabGroup);
            app.IMPORTANTTab.Title = 'IMPORTANT';
            app.Image = uiimage(app.IMPORTANTTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'dwight.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = POSTPROCESS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end